classdef pongGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        DebugText         matlab.ui.control.Label
        Player2Label      matlab.ui.control.Label
        Player1Label      matlab.ui.control.Label
        Pl2ScoreText      matlab.ui.control.Label
        Pl1ScoreText      matlab.ui.control.Label
        StartButton       matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        ToggleModeButton  matlab.ui.control.Button
        QuitButton        matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        config          % Speichern einiger Konfigurationsdaten
        brickObj        
        myTimer
        player1
        player2
        ball
    end
    
    %   -----
    %
    %   Variables to tweak:
    %   playerMaxSpeed in pongTimerCallback
    %   app.ball.vel in pongTimerStartCallback
    %   app.UIAxes.XLim in startupFcn
    %   app.UIAxes.YLim in startupFcn
    %   app.config.borderWidth in startupFcn
    %
    %   -----
    
    
    methods (Access = private)
        function pongTimerCallback(app, timerObj, event)
            disp("a");
            speed1 = double(app.player1.motor.tachoCount());
            if speed1 > app.config.controllerRange(2)
                speed1 = app.config.controllerRange(2);
            elseif speed1 < app.config.controllerRange(1)
                speed1 = app.config.controllerRange(1);
            end
            pos1 = speed1 * (app.UIAxes.YLim(2) - app.player1.bBox(2)) / app.config.controllerRange(2);
            app.player1.dir(2) = pos1 - app.player1.pos(2);
            app.player1.vel = abs(app.player1.dir(2));
            app.player1.pos(2) = pos1;
            disp("b");
            
            speed2 = double(app.player2.motor.tachoCount());
            if speed2 > app.config.controllerRange(2)
                speed2 = app.config.controllerRange(2);
            elseif speed2 < app.config.controllerRange(1)
                speed2 = app.config.controllerRange(1);
            end
            pos2 = speed2 * (app.UIAxes.YLim(2) - app.player2.bBox(2)) / app.config.controllerRange(2);
            app.player2.dir(2) = pos2 - app.player2.pos(2);
            app.player2.vel = abs(app.player2.dir(2));
            app.player2.pos(2) = pos2;
            disp("c");
            %calcContrPos(app, app.player1);
            %calcContrPos(app, app.player2);
            
            [obj, side] = checkCollision(app);
            disp("d");
            disp(obj);
            switch obj % [w1Border, w2Border, p1Box, p2Box, upBorder, downBorder]
                case 1
                    app.player1.score = app.player1.score + 1;
                    stop(timerObj);
                case 2
                    app.player2.score = app.player2.score + 1;
                    stop(timerObj);
                case 3
                    calcReflect(app, app.player1.dir, side);
                case 4
                    calcReflect(app, app.player2.dir, side);
                case 5
                    calcReflect(app, 0, side);
                case 6
                    calcReflect(app, 0, side);
                otherwise
                    
            end
            disp("e");
            app.ball.pos = app.ball.pos + app.ball.dir;
            app.ball.pos
            app.ball.vel
            app.ball.dir
            
            plotObjs(app);
            disp("f");
        end
        
        function pongTimerStartCallback(app, timerObj, event)
            disp('Timer wurde gestartet');
            dir = randi([-60, 60]);
            if dir < 0
                dir = dir - 150;
            else
                dir = dir - 30;
            end
            app.ball.pos = [double(0), double(0)];
            app.ball.vel = 0.3;
            app.ball.dir = [cosd(dir), sind(dir)] * app.ball.vel;
            
            app.player1.pos = [app.player1.pos(1), 0];
            app.player1.motor.internalReset();
            app.player1.motor.resetTachoCount();
            app.player2.pos = [app.player2.pos(1), 0];
            app.player2.motor.internalReset();
            app.player2.motor.resetTachoCount();
            
            plotObjs(app);
            
            for i = 3:-1:1
                app.DebugText.Text = num2str(i);
                pause(0.8);
            end
            app.DebugText.Text = "";
        end
        
        function pongTimerStopCallback(app, timerObj, event)
            disp('Timer wurde angehalten');
            app.Pl1ScoreText.Text = num2str(app.player1.score);
            app.Pl2ScoreText.Text = num2str(app.player2.score);
            if app.player1.score == app.config.maxPoints || app.player2.score == app.config.maxPoints || app.config.running == 0
                printScore(app);
            else
                pause(1);
                start(timerObj);
            end
        end
        
        function calcContrPos(app, player)
            disp("b1");
            speed = double(player.motor.tachoCount());
            speed
            if speed > app.config.controllerRange(2)
                speed = app.config.controllerRange(2);
            elseif speed < app.config.controllerRange(1)
                speed = app.config.controllerRange(1);
            end
            disp("b2");
            pos = speed * (app.UIAxes.YLim(2) - player.bBox(2)) / app.config.controllerRange(2);
            player.dir(2) = pos - player.pos(2);
            player.vel = abs(player.dir(2));
            player.pos(2) = pos;
            disp("b3");
            
        end
        
        function calcReflect(app, vec, side)
            disp("g");
            dirB = app.ball.dir;
            b = 0;
            switch side
                case 1
                    if dirB(1) > 0
                        dirB(1) = -dirB(1);
                        b = 1;
                    end
                case 2
                    if dirB(2) > 0
                        dirB(2) = -dirB(2);
                        b = 1;
                    end
                case 3
                    if dirB(1) < 0
                        dirB(1) = -dirB(1);
                        b = 1;
                    end
                case 4
                    if dirB(2) < 0
                        dirB(2) = -dirB(2);
                        b = 1;
                    end
            end
            disp("h");
            if b
                vel = norm(vec);
                vel
                if vel > app.config.ballSpeedCap - 1
                        vec = vec * (app.config.ballSpeedCap-1) / vel;
                        disp("x");
                        vec
                end
                dirB = vec + dirB * app.config.ballAcceleration;
                velB = norm(dirB);
                if velB > app.config.ballSpeedCap
                    dirB = dirB * app.config.ballSpeedCap / velB;
                    disp("y");
                    dirB
                end
                app.ball.dir = vec + dirB;
                app.ball.vel = norm(app.ball.dir);
            end
        end
        
        function plotObjs(app)
            hold(app.UIAxes, 'off');
            plot(app.UIAxes, [app.UIAxes.XLim(2)-app.config.borderWidth, app.UIAxes.XLim(2)-app.config.borderWidth, ...
                app.UIAxes.XLim(1)+app.config.borderWidth, app.UIAxes.XLim(1)+app.config.borderWidth], ...
                [app.UIAxes.YLim(1), app.UIAxes.YLim(2), app.UIAxes.YLim(2), app.UIAxes.YLim(1)],...
                "Color",'k', "LineStyle",'--');
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.ball.pos(1), app.ball.pos(2), ...
                'Marker','square', "MarkerFaceColor", 'b', "MarkerSize", 22);
            
            plot(app.UIAxes, [app.player1.pos(1), app.player1.pos(1)], ...
                [app.player1.pos(2)+app.player1.bBox(2), app.player1.pos(2)+app.player1.bBox(4)], ...
                "Color",'r', "LineWidth",19);
            plot(app.UIAxes, [app.player2.pos(1), app.player2.pos(1)], ...
                [app.player2.pos(2)+app.player2.bBox(2), app.player2.pos(2)+app.player2.bBox(4)], ...
                "Color",'r', "LineWidth",19);
            
%             plot(app.UIAxes, posb(1), posb(2), 'Marker', 'square');
%             plot(app.UIAxes, [pos1(1) pos1(1)], [pos1(2)+app.player1.bBox(2), pos1(2)+app.player1.bBox(4)]);
%             plot(app.UIAxes, [pos2(1) pos2(1)], [pos2(2)+app.player2.bBox(2), pos2(2)+app.player2.bBox(4)]);
        end
        
        function [obj, side] = checkCollision(app)
            ballBox = BoundBox(app, app.ball);
            p1Box = BoundBox(app, app.player1);
            p2Box = BoundBox(app, app.player2);
            upBorder = [app.player2.pos(1), app.UIAxes.YLim(2)+app.config.borderWidth, app.player1.pos(1), app.UIAxes.YLim(2)];
            downBorder = [app.player2.pos(1), app.UIAxes.YLim(1), app.player1.pos(1), app.UIAxes.YLim(1)-app.config.borderWidth];
            w1Border = [app.UIAxes.XLim(2), app.UIAxes.YLim(2), app.player2.pos(1), app.UIAxes.YLim(1)];
            w2Border = [app.player1.pos(1), app.UIAxes.YLim(2), app.UIAxes.XLim(1), app.UIAxes.YLim(1)];
            
            oList = reshape([w1Border, w2Border, p1Box, p2Box, upBorder, downBorder], [4,6]);
            obj = NaN;
            side = NaN;
            threshold = [app.ball.dir(1), app.ball.dir(2), -app.ball.dir(1), -app.ball.dir(2)];
            ballBox
            threshold
            oList
            for o = 1:length(oList)
                oBox = oList(:, o);
                if ~isnan(obj)
                    break;
                end
                for c = 1:4
                    if c > 2
                        a = -1;
                    else
                        a = 1;
                    end
                    if abs(ballBox(c)-oBox(c+a*2)) <= threshold(c)
                        i = mod(c, 2)+2;
                        if ballBox(i-1) > oBox(i+1) && ballBox(i+1) < oBox(i-1)
                            side = c;
                            obj = o;
                            disp("obj");
                            disp(obj);
                            disp(side);
                            disp(oBox);
                            break;
                        end
                    end
                end
            end
        end
        
        function printScore(app)
            score1 = app.player1.score;
            score2 = app.player2.score;
            if score1 < score2
                app.DebugText.Text = "Player 2 wins!";
            elseif score1 > score2
                app.DebugText.Text = "Player 1 wins!";
            else
                app.DebugText.Text = "It's a tie!";
            end
        end
        
        function borders = BoundBox(~, obj)
            pos = obj.pos;
            bBox = obj.bBox;
            borders = [pos(1)+bBox(1), pos(2)+bBox(2), pos(1)+bBox(3), pos(2)+bBox(4)];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            objStruct.pos = [0, 0];
            objStruct.vel = 0;
            objStruct.dir = [0, 0];
            objStruct.bBox = [1, 1, -1, -1];
            %objStruct = setvartype(objStruct, {'pos', 'vel', 'dir'}, 'double');
            
            app.config.maxPoints            = 10;
            app.config.timerPeriod          = 0.05;
            app.config.timerExecutionMode   = 'fixedRate';
            app.config.brickConnectionType  = 'usb';
            app.config.running              = 0;
            app.config.controllerRange      = [-80, 80];
            app.config.borderWidth          = 2;
            app.config.ballAcceleration     = 1.01;
            app.config.ballSpeedCap         = 1.5;
            
            app.UIAxes.XLim = [-12 12];
            app.UIAxes.YLim = [-8 8];
            
            app.ball = objStruct;
            app.ball.bBox = [0.5, 0.5, -0.5, -0.5];
            app.player1 = objStruct;
            app.player1.pos = [app.UIAxes.XLim(1)+app.config.borderWidth, 0];
            app.player1.bBox = [0.5, 2, -0.5, -2];
            app.player2 = objStruct;
            app.player2.pos = [app.UIAxes.XLim(2)-app.config.borderWidth, 0];
            app.player2.bBox = [0.5, 2, -0.5, -2];
            % Speichern einiger Konfigurationsdaten in den app-Properties
            
            
            % Bluetooth initialisieren und Verbindung oeffnen
            try
                app.brickObj = EV3();
                app.brickObj.connect(app.config.brickConnectionType);
                disp("connected");
            catch errinfo
                disp('Verbindung konnte nicht hergestellt werden');
                disp(errinfo);
            end
            app.player1.motor = app.brickObj.motorA;
            app.player1.motor.internalReset();
            app.player1.motor.resetTachoCount();
            app.player1.score = 0;
            app.player2.motor = app.brickObj.motorB;
            app.player2.motor.internalReset();
            app.player2.motor.resetTachoCount();
            app.player2.score = 0;
            
            % Daten fuer UserData Eigenschaft des Timer-Objektes vorbereiten
            myUserData.ball = app.ball;
            myUserData.player1 = app.player1;
            myUserData.player2 = app.player2;
            %myUserData.brickObj = app.brickObj;
            
            % Timer-Objekt erzeugen, konfigurieren und in den app-Properties speichern
            app.myTimer = timer(...
                'Period',        app.config.timerPeriod, ...
                'ExecutionMode', app.config.timerExecutionMode, ...
                'UserData',      myUserData, ...
                'StartFcn',     @app.pongTimerStartCallback, ...
                'StopFcn',      @app.pongTimerStopCallback, ...
                'TimerFcn',     @app.pongTimerCallback);
            
            % Den aktuellen Mode in der GUI anzeigen
            app.Pl1ScoreText.Text = num2str(app.player1.score);
            app.Pl2ScoreText.Text = num2str(app.player2.score);
            
            plotObjs(app);
            
            % Initiales Plotten der Daten (muss man nicht unbedingt machen)
            %plot(app.UIAxes, myUserData.measurementData);
            
            % Testen der Object Bounding Boxen auf der GUI
%             hold(app.UIAxes, "on");
%             obj = app.ball;
%             plot(app.UIAxes, obj.pos(1), obj.pos(2), 'Marker','square',
%             "MarkerFaceColor", 'b', "MarkerSize", 22); % Ball
%             %plot(app.UIAxes, [obj.pos(1), obj.pos(1)],
%             [obj.pos(2)+obj.bBox(2), obj.pos(2)+obj.bBox(4)],
%             "Color",'r', "LineWidth",19); % Player
%             
%             plot(app.UIAxes, app.UIAxes.XLim, [obj.bBox(2)+obj.pos(2), obj.bBox(2)+obj.pos(2)]);
%             plot(app.UIAxes, app.UIAxes.XLim, [obj.bBox(4)+obj.pos(2), obj.bBox(4)+obj.pos(2)]);
%             plot(app.UIAxes, [obj.bBox(1)+obj.pos(1), obj.bBox(1)+obj.pos(1)], app.UIAxes.YLim);
%             plot(app.UIAxes, [obj.bBox(3)+obj.pos(1), obj.bBox(3)+obj.pos(1)], app.UIAxes.YLim);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % <-- Your Code Here -->
            %stop(app.myTimer);
            delete(app.myTimer);
            
            
            app.brickObj.disconnect();
            
            % Hint: delete(app) closes the figure
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if(strcmp(app.myTimer.Running, 'off'))
                app.player1.score = 0;
                app.player2.score = 0;
            
                plotObjs(app);
                app.config.running = 1;
                start(app.myTimer);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if(strcmp(app.myTimer.Running, 'on'))
                app.config.running = 0;
                stop(app.myTimer);
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: ToggleModeButton
        function ToggleModeButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ToggleModeButton = uibutton(app.UIFigure, 'push');
            app.ToggleModeButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleModeButtonPushed, true);
            app.ToggleModeButton.Text = 'Toggle Mode';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Pl1ScoreText = uilabel(app.UIFigure);
            app.Pl1ScoreText.Text = '';
            app.Pl2ScoreText = uilabel(app.UIFigure);
            app.Pl2ScoreText.Text = '';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1:';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2:';
            app.DebugText = uilabel(app.UIFigure);
            app.DebugText.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pongGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end