classdef pongGuicopy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        QuitButton        matlab.ui.control.Button
        ToggleModeButton  matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        StartButton       matlab.ui.control.Button
        Pl1ScoreText      matlab.ui.control.Label
        Pl2ScoreText      matlab.ui.control.Label
        Player1Label      matlab.ui.control.Label
        Player2Label      matlab.ui.control.Label
        DebugText         matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        config
        brickObj
        myTimer

        player1
        player2
        ball

        % Plot handles (performance)
        hBorder
        hBall
        hP1
        hP2

        % Mode
        isSinglePlayer logical = false
    end

    % ---------------------------------------------------------------------
    % Main game logic
    % ---------------------------------------------------------------------
    methods (Access = private)

        function pongTimerStartCallback(app, timerObj, ~)
            disp('Timer wurde gestartet');

            % Reset ball each round
            app.resetBallForNewRound();

            % Countdown
            for i = 3:-1:1
                app.DebugText.Text = num2str(i);
                pause(0.6);
            end
            app.DebugText.Text = "";
        end

        function pongTimerStopCallback(app, timerObj, ~)
            disp('Timer wurde angehalten');

            app.Pl1ScoreText.Text = num2str(app.player1.score);
            app.Pl2ScoreText.Text = num2str(app.player2.score);

            if app.player1.score >= app.config.maxPoints || app.player2.score >= app.config.maxPoints || app.config.running == 0
                app.printScore();
                return;
            end

            pause(0.7);
            start(timerObj); % StartFcn setzt Ball neu
        end

        function pongTimerCallback(app, timerObj, ~)

            % ---------- Read Paddle Positions ----------
            % Player 1 from motor A (with hard clamping mapping)
            oldY1 = app.player1.pos(2);
            y1 = app.readPaddleYFromMotor(app.player1, oldY1);
            app.player1.vel = y1 - oldY1;
            app.player1.pos(2) = y1;

            % Player 2 from motor B OR AI (Singleplayer)
            oldY2 = app.player2.pos(2);
            if app.isSinglePlayer
                y2 = app.clampPaddleY(app.ball.pos(2), app.player2);
            else
                y2 = app.readPaddleYFromMotor(app.player2, oldY2);
            end
            app.player2.vel = y2 - oldY2;
            app.player2.pos(2) = y2;

            % ---------- Ball Movement (predict next position) ----------
            nextPos = app.ball.pos + app.ball.dir * app.ball.speed;

            % Ball radius from bbox
            r = abs(app.ball.bBox(1));

            xMin = app.UIAxes.XLim(1);
            xMax = app.UIAxes.XLim(2);
            yMin = app.UIAxes.YLim(1);
            yMax = app.UIAxes.YLim(2);
            bw   = app.config.borderWidth;

            epsPush = 1e-3;  % prevents "sticking"

            % ---------- Score (left/right goal) ----------
            if nextPos(1) - r <= xMin
                app.player2.score = app.player2.score + 1;
                stop(timerObj);
                return;
            elseif nextPos(1) + r >= xMax
                app.player1.score = app.player1.score + 1;
                stop(timerObj);
                return;
            end

            % ---------- Top / Bottom walls ----------
            if nextPos(2) + r >= (yMax - bw)
                app.ball.dir(2) = -abs(app.ball.dir(2));
                nextPos(2) = (yMax - bw) - r - epsPush;
            elseif nextPos(2) - r <= (yMin + bw)
                app.ball.dir(2) = abs(app.ball.dir(2));
                nextPos(2) = (yMin + bw) + r + epsPush;
            end

            % ---------- Paddle collisions (AABB) ----------
            [p1L,p1R,p1B,p1T] = app.paddleAABB(app.player1);
            [p2L,p2R,p2B,p2T] = app.paddleAABB(app.player2);

            bL = nextPos(1) - r;  bR = nextPos(1) + r;
            bB = nextPos(2) - r;  bT = nextPos(2) + r;

            didPaddleHit = false;

            % Player 1 (left)
            if bL <= p1R && bR >= p1L && bB <= p1T && bT >= p1B && app.ball.dir(1) < 0
                didPaddleHit = true;

                % reflect X
                app.ball.dir(1) = abs(app.ball.dir(1));

                % angle tweak based on hit position (classic pong feel)
                hit = (nextPos(2) - app.player1.pos(2)) / max(1e-12, abs(app.player1.bBox(2))); % approx -1..1
                app.ball.dir(2) = app.ball.dir(2) + 0.35 * hit;

                % push ball out of paddle
                nextPos(1) = p1R + r + epsPush;
            end

            % Player 2 (right)
            if bR >= p2L && bL <= p2R && bB <= p2T && bT >= p2B && app.ball.dir(1) > 0
                didPaddleHit = true;

                % reflect X
                app.ball.dir(1) = -abs(app.ball.dir(1));

                hit = (nextPos(2) - app.player2.pos(2)) / max(1e-12, abs(app.player2.bBox(2)));
                app.ball.dir(2) = app.ball.dir(2) + 0.35 * hit;

                nextPos(1) = p2L - r - epsPush;
            end

            % ---------- Speed increases with every exchange ----------
            if didPaddleHit
                app.ball.speed = min(app.config.maxBallSpeed, app.ball.speed + app.config.hitSpeedIncrement);
            end

            % Normalize direction (prevents drifting)
            app.ball.dir = app.ball.dir / max(1e-12, norm(app.ball.dir));

            % Commit
            app.ball.pos = nextPos;

            % Render (fast update)
            app.updatePlots();
            drawnow limitrate;
        end

        % -----------------------------------------------------------------
        % Game state helpers
        % -----------------------------------------------------------------
        function resetBallForNewRound(app)
            app.ball.pos = [0, 0];

            app.ball.speed = app.config.ballStartSpeed;
            ang = randi([-60, 60]);
            if ang < 0
                ang = ang - 150;
            else
                ang = ang - 30;
            end

            app.ball.dir = [cosd(ang), sind(ang)];
            app.ball.dir = app.ball.dir / max(1e-12, norm(app.ball.dir));
        end

        function resetGame(app)
            % Stop timer if running
            if ~isempty(app.myTimer) && isvalid(app.myTimer) && strcmp(app.myTimer.Running,'on')
                stop(app.myTimer);
            end

            app.player1.score = 0;
            app.player2.score = 0;
            app.Pl1ScoreText.Text = "0";
            app.Pl2ScoreText.Text = "0";
            app.DebugText.Text = "";

            % Reset paddles to center
            app.player1.pos(2) = 0;
            app.player2.pos(2) = 0;
            app.player1.vel = 0;
            app.player2.vel = 0;

            % Reset motors tacho counts (keeps "internal" in sync)
            try
                if isfield(app.player1,'motor') && ~isempty(app.player1.motor)
                    app.player1.motor.resetTachoCount();
                end
                if isfield(app.player2,'motor') && ~isempty(app.player2.motor)
                    app.player2.motor.resetTachoCount();
                end
            catch
            end

            % Reset ball
            app.resetBallForNewRound();

            % Render
            app.updatePlots();
            drawnow;
        end

        % -----------------------------------------------------------------
        % Paddle mapping + clamping (fixes "out of scope scroll")
        % -----------------------------------------------------------------
        function y = readPaddleYFromMotor(app, p, fallbackY)
            % If no motor connected, keep current
            if ~isfield(p,'motor') || isempty(p.motor)
                y = app.clampPaddleY(fallbackY, p);
                return;
            end

            t = double(p.motor.tachoCount());
            t = min(max(t, app.config.controllerRange(1)), app.config.controllerRange(2));

            % Map controller range -> allowed Y range
            yMinAllowed = app.UIAxes.YLim(1) + app.config.borderWidth + abs(p.bBox(2));
            yMaxAllowed = app.UIAxes.YLim(2) - app.config.borderWidth - abs(p.bBox(2));

            tMin = app.config.controllerRange(1);
            tMax = app.config.controllerRange(2);

            % Linear map
            alpha = (t - tMin) / max(1e-12, (tMax - tMin)); % 0..1
            y = yMinAllowed + alpha * (yMaxAllowed - yMinAllowed);

            % Hard clamp (just in case)
            y = min(max(y, yMinAllowed), yMaxAllowed);
        end

        function y = clampPaddleY(app, y, p)
            yMinAllowed = app.UIAxes.YLim(1) + app.config.borderWidth + abs(p.bBox(2));
            yMaxAllowed = app.UIAxes.YLim(2) - app.config.borderWidth - abs(p.bBox(2));
            y = min(max(y, yMinAllowed), yMaxAllowed);
        end

        function [L,R,B,T] = paddleAABB(app, p)
            %#ok<INUSD>
            xR = p.pos(1) + p.bBox(1);
            xL = p.pos(1) + p.bBox(3);
            yT = p.pos(2) + p.bBox(2);
            yB = p.pos(2) + p.bBox(4);
            L = min(xL,xR); R = max(xL,xR);
            B = min(yB,yT); T = max(yB,yT);
        end

        function printScore(app)
            s1 = app.player1.score;
            s2 = app.player2.score;
            if s1 < s2
                app.DebugText.Text = "Player 2 wins!";
            elseif s1 > s2
                app.DebugText.Text = "Player 1 wins!";
            else
                app.DebugText.Text = "It's a tie!";
            end
        end

        % -----------------------------------------------------------------
        % Plotting (optimized with handles)
        % -----------------------------------------------------------------
        function initPlots(app)
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');

            x1 = app.UIAxes.XLim(1) + app.config.borderWidth;
            x2 = app.UIAxes.XLim(2) - app.config.borderWidth;
            y1 = app.UIAxes.YLim(1) + app.config.borderWidth;
            y2 = app.UIAxes.YLim(2) - app.config.borderWidth;

            app.hBorder = plot(app.UIAxes, [x2 x2 x1 x1 x2], [y1 y2 y2 y1 y1], 'k--');

            app.hBall = plot(app.UIAxes, app.ball.pos(1), app.ball.pos(2), ...
                's', 'MarkerFaceColor', 'b', 'MarkerSize', 22, 'LineStyle', 'none');

            app.hP1 = plot(app.UIAxes, [app.player1.pos(1) app.player1.pos(1)], ...
                [app.player1.pos(2)+app.player1.bBox(4), app.player1.pos(2)+app.player1.bBox(2)], ...
                'r-', 'LineWidth', 19);

            app.hP2 = plot(app.UIAxes, [app.player2.pos(1) app.player2.pos(1)], ...
                [app.player2.pos(2)+app.player2.bBox(4), app.player2.pos(2)+app.player2.bBox(2)], ...
                'r-', 'LineWidth', 19);

            hold(app.UIAxes, 'off');
        end

        function updatePlots(app)
            app.hBall.XData = app.ball.pos(1);
            app.hBall.YData = app.ball.pos(2);

            app.hP1.XData = [app.player1.pos(1) app.player1.pos(1)];
            app.hP1.YData = [app.player1.pos(2)+app.player1.bBox(4), app.player1.pos(2)+app.player1.bBox(2)];

            app.hP2.XData = [app.player2.pos(1) app.player2.pos(1)];
            app.hP2.YData = [app.player2.pos(2)+app.player2.bBox(4), app.player2.pos(2)+app.player2.bBox(2)];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % --- Disable UIAxes scroll/zoom/pan (fix: "out of scope scrolln") ---
            try
                disableDefaultInteractivity(app.UIAxes);
            catch
                % older MATLAB fallback (best effort)
                try
                    app.UIAxes.Interactions = [];
                catch
                end
            end
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLimMode = 'manual';

            % Base object struct
            objStruct.pos = [0, 0];
            objStruct.vel = 0;
            objStruct.dir = [0, 0];
            objStruct.bBox = [1, 1, -1, -1];

            % Config
            app.config.maxPoints            = 10;
            app.config.timerPeriod          = 0.05;
            app.config.timerExecutionMode   = 'fixedRate';
            app.config.brickConnectionType  = 'usb';
            app.config.running              = 0;
            app.config.controllerRange      = [-80, 80];
            app.config.borderWidth          = 2;

            % Ball speed settings (your requested behavior)
            app.config.ballStartSpeed       = 0.25;  % speed at round start
            app.config.hitSpeedIncrement    = 0.03;  % +speed each paddle exchange
            app.config.maxBallSpeed         = 1.20;  % cap

            % Field size
            app.UIAxes.XLim = [-12 12];
            app.UIAxes.YLim = [-8 8];

            % Ball
            app.ball = objStruct;
            app.ball.bBox = [0.5, 0.5, -0.5, -0.5];
            app.ball.speed = app.config.ballStartSpeed;
            app.ball.dir = [1 0];

            % Players
            app.player1 = objStruct;
            app.player1.pos = [app.UIAxes.XLim(1)+app.config.borderWidth, 0];
            app.player1.bBox = [0.5, 2, -0.5, -2];

            app.player2 = objStruct;
            app.player2.pos = [app.UIAxes.XLim(2)-app.config.borderWidth, 0];
            app.player2.bBox = [0.5, 2, -0.5, -2];

            % Connect EV3
            try
                app.brickObj = EV3();
                app.brickObj.connect(app.config.brickConnectionType);
                disp("connected");
            catch errinfo
                disp('Verbindung konnte nicht hergestellt werden');
                disp(errinfo);
                app.brickObj = [];
            end

            % Motors (guard if EV3 not connected)
            if ~isempty(app.brickObj)
                app.player1.motor = app.brickObj.motorA;
                app.player1.motor.internalReset();
                app.player1.motor.resetTachoCount();

                app.player2.motor = app.brickObj.motorB;
                app.player2.motor.internalReset();
                app.player2.motor.resetTachoCount();
            else
                app.player1.motor = [];
                app.player2.motor = [];
            end

            app.player1.score = 0;
            app.player2.score = 0;

            % Timer
            app.myTimer = timer( ...
                'Period',        app.config.timerPeriod, ...
                'ExecutionMode', app.config.timerExecutionMode, ...
                'StartFcn',      @app.pongTimerStartCallback, ...
                'StopFcn',       @app.pongTimerStopCallback, ...
                'TimerFcn',      @app.pongTimerCallback);

            % Init UI
            app.Pl1ScoreText.Text = "0";
            app.Pl2ScoreText.Text = "0";

            % Initialize plot handles
            app.initPlots();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             try
                if ~isempty(app.myTimer) && isvalid(app.myTimer)
                    stop(app.myTimer);
                    delete(app.myTimer);
                end
            catch
            end

            try
                if ~isempty(app.brickObj)
                    % app.brickObj.disconnect(); % if supported/needed
                end
            catch
            end

            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if strcmp(app.myTimer.Running, 'off')
                            app.config.running = 1;
                            app.resetGame();      % <-- reset points & state on every start
                            start(app.myTimer);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.config.running = 0;
            app.resetGame();          % <-- reset points & state on stop
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: ToggleModeButton
        function ToggleModeButtonPushed(app, event)
             app.isSinglePlayer = ~app.isSinglePlayer;

            % Reset points when mode changes (as requested)
            app.resetGame();

            if app.isSinglePlayer
                app.DebugText.Text = "Mode: Singleplayer (AI)";
            else
                app.DebugText.Text = "Mode: 2 Players";
            end
            pause(0.8);
            app.DebugText.Text = "";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ToggleModeButton = uibutton(app.UIFigure, 'push');
            app.ToggleModeButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleModeButtonPushed, true);
            app.ToggleModeButton.Text = 'Toggle Mode';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Pl1ScoreText = uilabel(app.UIFigure);
            app.Pl1ScoreText.Text = '';
            app.Pl2ScoreText = uilabel(app.UIFigure);
            app.Pl2ScoreText.Text = '';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1:';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2:';
            app.DebugText = uilabel(app.UIFigure);
            app.DebugText.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pongGuicopy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end