classdef pongGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        QuitButton        matlab.ui.control.Button
        ToggleModeButton  matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        StartButton       matlab.ui.control.Button
        Pl1ScoreText      matlab.ui.control.Label
        Pl2ScoreText      matlab.ui.control.Label
        Player1Label      matlab.ui.control.Label
        Player2Label      matlab.ui.control.Label
        DebugText         matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        config          % Speichern einiger Konfigurationsdaten
        brickObj        
        myTimer
        player1
        player2
        ball
    end
    
    %   -----
    %
    %   Variables to tweak:
    %   playerMaxSpeed in pongTimerCallback
    %   app.ball.vel in pongTimerStartCallback
    %   app.UIAxes.XLim in startupFcn
    %   app.UIAxes.YLim in startupFcn
    %   app.config.borderWidth in startupFcn
    %
    %   -----
    
    
    methods (Access = private)
        function pongTimerCallback(app, timerObj, event)
            playerMaxSpeed = 2;
            speed1 = app.player1.motor.tachoCount();
            if speed1 > app.config.controllerRange(2)
                speed1 = app.config.controllerRange(2);
            elseif speed1 < app.config.controllerRange(1)
                speed1 = app.config.controllerRange(1);
            end
            
            
            speed2 = app.player2.motor.tachoCount();
            if speed2 > app.config.controllerRange(2)
                speed2 = app.config.controllerRange(2);
            elseif speed2 < app.config.controllerRange(1)
                speed2 = app.config.controllerRange(1);
            end
            
            
            [obj, side] = checkCollision(app);
            switch obj % [w1Border, w2Border, p1Box, p2Box, upBorder, downBorder]
                case 1
                    app.player1.score = app.player1.score + 1;
                    stop(timerObj);
                case 2
                    app.player1.score = app.player2.score + 1;
                    stop(timerObj);
                otherwise
                    app.ball.pos = app.ball.pos + app.ball.dir;
                    
            end
            plotObjs(app)
            
        end
        
        function pongTimerStartCallback(app, timerObj, event)
            disp('Timer wurde gestartet');
            dir = randi([-60, 60]);
            if dir < 0
                dir = dir - 150;
            else
                dir = dir - 30;
            end
            app.ball.pos = [0, 0];
            app.ball.dir = [cosd(dir), sind(dir)];
            app.ball.vel = 0.1;
            
            for i = 3:-1:1
                app.DebugText.Text = num2str(i);
                pause(0.8);
            end
            app.DebugText.Text = "";
        end
        
        function pongTimerStopCallback(app, timerObj, event)
            disp('Timer wurde angehalten');
            app.Pl1ScoreText.Text = num2str(app.player1.score);
            app.Pl2ScoreText.Text = num2str(app.player2.score);
            if app.player1.score == app.config.maxPoints || app.player2.score == app.config.maxPoints || app.config.running == 0
                printScore(app);
            else
                pause(1);
                start(timerObj);
            end
        end
        
        function plotObjs(app)
            posb = app.ball.pos;
            pos1 = app.player1.pos;
            pos2 = app.player2.pos;
            fig = figure;
            hold on;
            plot(app.UIAxes, posb(1), posb(2), 'Marker', 'square');
            plot(app.UIAxes, [pos1(1) pos1(1)], [pos1(2)+app.player1.bBox(2), pos1(2)+app.player1.bBox(4)]);
            plot(app.UIAxes, [pos2(1) pos2(1)], [pos2(2)+app.player2.bBox(2), pos2(2)+app.player2.bBox(4)]);
        end
        
        function [obj, side] = checkCollision(app)
            ballBox = BoundBox(app, app.ball);
            p1Box = BoundBox(app, app.player1);
            p2Box = BoundBox(app, app.player2);
            upBorder = [app.player2.pos(1), app.UIAxes.YLim(2)+app.config.borderWidth, app.player1.pos(1), app.UIAxes.YLim(2)];
            downBorder = [app.player2.pos(1), app.UIAxes.YLim(1), app.player1.pos(1), app.UIAxes.YLim(1)-app.config.borderWidth];
            w1Border = [app.UIAxes.XLim(2), app.UIAxes.YLim(2), app.player2.pos(1), app.UIAxes.YLim(1)];
            w2Border = [app.player1.pos(1), app.UIAxes.YLim(2), app.UIAxes.XLim(1), app.UIAxes.YLim(1)];
            
            oList = [w1Border, w2Border, p1Box, p2Box, upBorder, downBorder];
            obj = NaN;
            side = NaN;
            threshold = app.ball.vel;
            for o = 1:length(oList)
                oBox = oList(o);
                if ~isnan(obj)
                    break;
                end
                for c = 1:4
                    if c > 2
                        a = -1;
                    else
                        a = 1;
                    end
                    if abs(ballBox(c)-oBox(c+a*2)) <= threshold
                        i = mod(c, 2)+2;
                        if ballBox(i-1) > oBox(i+1) && ballBox(i+1) < oBox(i-1)
                            side = c;
                            obj = o;
                            break;
                        end
                    end
                end
            end
        end
        
        function printScore(app)
            score1 = app.player1.score;
            score2 = app.player2.score;
            if score1 < score2
                app.DebugText.Text = "Player 2 wins!";
            elseif score1 > score2
                app.DebugText.Text = "Player 1 wins!";
            else
                app.DebugText.Text = "It's a tie!";
            end
        end
        
        function borders = BoundBox(~, obj)
            pos = obj.pos;
            bBox = obj.bBox;
            borders = [pos(1)+bBox(1), pos(2)+bBox(2), pos(1)+bBox(3), pos(2)+bBox(4)];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            objStruct.pos = [0, 0];
            objStruct.vel = 0;
            objStruct.dir = [0, 0];
            objStruct.bBox = [1, 1, -1, -1];
            
            app.config.maxPoints            = 10;
            app.config.timerPeriod          = 0.05;
            app.config.timerExecutionMode   = 'fixedRate';
            app.config.brickConnectionType  = 'usb';
            app.config.running              = 0;
            app.config.controllerRange      = [-80, 80];
            app.config.borderWidth          = 2;
            
            app.UIAxes.XLim = [-12 12];
            app.UIAxes.YLim = [-8 8];
            
            app.ball = objStruct;
            app.ball.bBox = [0.5, 0.5, -0.5, -0.5];
            app.player1 = objStruct;
            app.player1.pos = [app.UIAxes.XLim(1)+app.config.borderWidth, 0];
            app.player1.bBox = [0.5, 2, -0.5, -2];
            app.player2 = objStruct;
            app.player2.pos = [app.UIAxes.XLim(2)-app.config.borderWidth, 0];
            app.player2.bBox = [0.5, 2, -0.5, -2];
            % Speichern einiger Konfigurationsdaten in den app-Properties
            
            
            % Bluetooth initialisieren und Verbindung oeffnen
            try
                app.brickObj = EV3();
                app.brickObj.connect(app.config.brickConnectionType);
            catch errinfo
                disp('Verbindung konnte nicht hergestellt werden');
                disp(errinfo);
            end
            app.player1.motor = app.brickObj.motorA;
            app.player1.score = 0;
            app.player2.motor = app.brickObj.motorB;
            app.player2.score = 0;
            
            % Daten fuer UserData Eigenschaft des Timer-Objektes vorbereiten
            myUserData.ball = app.ball;
            myUserData.player1 = app.player1;
            myUserData.player2 = app.player2;
            %myUserData.brickObj = app.brickObj;
            
            % Timer-Objekt erzeugen, konfigurieren und in den app-Properties speichern
            app.myTimer = timer(...
                'Period',        app.config.timerPeriod, ...
                'ExecutionMode', app.config.timerExecutionMode, ...
                'UserData',      myUserData, ...
                'StartFcn',     @app.pongTimerStartCallback, ...
                'StopFcn',      @app.pongTimerStopCallback, ...
                'TimerFcn',     @app.pongTimerCallback);
            
            % Den aktuellen Mode in der GUI anzeigen
            app.Pl1ScoreText.Text = num2str(app.player1.score);
            app.Pl2ScoreText.Text = num2str(app.player2.score);
            
            % Initiales Plotten der Daten (muss man nicht unbedingt machen)
            %plot(app.UIAxes, myUserData.measurementData);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % <-- Your Code Here -->
            %stop(app.myTimer);
            delete(app.myTimer);
            
            
            %app.brickObj.disconnect();
            
            % Hint: delete(app) closes the figure
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if(strcmp(app.myTimer.Running, 'off'))
                app.config.running = 1;
                start(app.myTimer);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if(strcmp(app.myTimer.Running, 'on'))
                app.config.running = 0;
                stop(app.myTimer);
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: ToggleModeButton
        function ToggleModeButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ToggleModeButton = uibutton(app.UIFigure, 'push');
            app.ToggleModeButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleModeButtonPushed, true);
            app.ToggleModeButton.Text = 'Toggle Mode';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Pl1ScoreText = uilabel(app.UIFigure);
            app.Pl1ScoreText.Text = '';
            app.Pl2ScoreText = uilabel(app.UIFigure);
            app.Pl2ScoreText.Text = '';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1:';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2:';
            app.DebugText = uilabel(app.UIFigure);
            app.DebugText.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pongGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end